classdef ContentScreen < matlab.apps.AppBase

    % Properties that correspond to app components
    properties (Access = public)
        UIFigure               matlab.ui.Figure
        Toolbar                matlab.ui.container.Toolbar
        BackButton             matlab.ui.container.toolbar.PushTool
        Panel                  matlab.ui.container.Panel
        Panel_3                matlab.ui.container.Panel
        SecondSummaryLabel     matlab.ui.control.Label
        FirstSummaryLabel      matlab.ui.control.Label
        Panel_2                matlab.ui.container.Panel
        TabGroup               matlab.ui.container.TabGroup
        TrendoftheVariableTab  matlab.ui.container.Tab
        Plot                   matlab.ui.control.UIAxes
    end

    
    properties (Access = private)
        pathToMLAPP = fileparts(mfilename('fullpath'));
        pathToBackend = fullfile(fileparts(mfilename('fullpath')), '..', 'backend');
        pathToDatasets = fullfile(fileparts(mfilename('fullpath')), '..', 'backend', 'dataset');
    end
    
    methods (Access = private)
        
        function createLoggingFile(app)

            % Apro il file in scrittura
            logPath = fullfile(app.pathToDatasets, 'log.txt');
            fileID = fopen(logPath, 'w');

            % Scrivo i dati nel file
            fprintf(fileID, 'This file may contain Backend log printouts in case of application failure...');

            % Chiudo il file
            fclose(fileID);

        end
    end
    

    % Callbacks that handle component events
    methods (Access = private)

        % Code that executes after component creation
        function startupFcn(app)
            % Posizino la finestra correttamente al centro dello schermo in
            % maniera indipendente dalla risoluzione di quest'ultimo
            % Ottengo la larghezza e l'altezza dello schermo
            screenSize = get(groot, 'ScreenSize');
            screenWidth = screenSize(3);
            screenHeight = screenSize(4);

            % Ottengo la larghezza e l'altezza della finestra dell'app
            appWidth = app.UIFigure.Position(3);
            appHeight = app.UIFigure.Position(4);

            % Calcolo le coordinate x e y per posizionare la finestra al centro
            x = (screenWidth - appWidth) / 2;
            y = (screenHeight - appHeight) / 2;

            % Imposto la posizione della finestra
            app.UIFigure.Position(1) = x;
            app.UIFigure.Position(2) = y;

            app.UIFigure.Name = 'CMIP6 Climate Projections Visualizer';
            borderColor = [0.64, 0.08, 0.18];
            app.Panel_2.BorderColor = borderColor;
            app.Panel_3.BorderColor = borderColor;

            whichExperiment = evalin('base', 'whichExperiment');
            whichStartingYear = evalin('base', 'whichStartingYear');
            whichFinalYear = evalin('base', 'whichFinalYear');
            whichVariable = evalin('base', 'whichVariable');
            whichModel = evalin('base', 'whichModel');
            yearsString = evalin('base', 'yearsString');
            variable = evalin('base', 'variable');

            addpath(genpath(app.pathToBackend));

            summaryString1 = sprintf('<strong>Experiment: </strong>%s\n<strong>          Years: </strong>%s-%s',whichExperiment, num2str(whichStartingYear), num2str(whichFinalYear));
            summaryString2 = sprintf('<strong>Variable: </strong>%s\n<strong>   Model: </strong>%s',whichVariable, whichModel);
            app.FirstSummaryLabel.Text = summaryString1;
            app.SecondSummaryLabel.Text = summaryString2;

            pathToDatasetFolder = evalin('base', 'pathToDatasetFolder');
            addpath(pathToDatasetFolder);
            datasetName = evalin('base', 'datasetName');

            try
                [time,lat,lon,snw] = leggiDati(pathToDatasetFolder, datasetName, app.pathToBackend);
                [snowBasilicata] = filtraDati(lat,lon,snw);
                plotDati(snowBasilicata,variable,time,yearsString, true, app);
            catch
                errordlg('Unfortunately an error occurred while printing the diagram, this is often due to the lack of enough data to perform the interpolation, please try again with a larger interval of years!','Error');
                return;
            end
        end

        % Callback function: BackButton
        function BackButtonClicked(app, event)
            eliminaDataset(true, app.pathToDatasets, app.pathToBackend);
            evalin('base', 'clc');
            createLoggingFile(app);
            assignin('base', 'isStartedFromContentsScreen', 1);
            % Chiudo lo schermo dei contenuti
            delete(app);
            % Apro lo schermo di benvenuto
            WelcomeScreen
        end

        % Close request function: UIFigure
        function UIFigureCloseRequest(app, event)
            evalin('base', 'clear variables');
            evalin('base', 'clc');
            delete(app);
        end
    end

    % Component initialization
    methods (Access = private)

        % Create UIFigure and components
        function createComponents(app)
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            % Get the file path for locating images
            pathToMLAPP = fileparts(mfilename('fullpath'));
            app.UIFigure = uifigure('Visible', 'off');
            app.UIFigure.Name = 'MATLAB App';
            app.UIFigure.CloseRequestFcn = createCallbackFcn(app, @UIFigureCloseRequest, true);
            app.Toolbar = uitoolbar(app.UIFigure);
            app.BackButton = uipushtool(app.Toolbar);
            app.BackButton.Tooltip = {'Return to the previous screen'};
            app.BackButton.ClickedCallback = createCallbackFcn(app, @BackButtonClicked, true);
            app.BackButton.Icon = fullfile(pathToMLAPP, 'res', 'back_button.png');
            app.Panel = uipanel(app.UIFigure);
            app.Panel_2 = uipanel(app.Panel);
            app.TabGroup = uitabgroup(app.Panel_2);
            app.TrendoftheVariableTab = uitab(app.TabGroup);
            app.TrendoftheVariableTab.Title = 'Trend of the Variable';
            app.Plot = uiaxes(app.TrendoftheVariableTab);
            title(app.Plot, 'Title')
            xlabel(app.Plot, 'X')
            ylabel(app.Plot, 'Y')
            zlabel(app.Plot, 'Z')
            app.Panel_3 = uipanel(app.Panel);
            app.FirstSummaryLabel = uilabel(app.Panel_3);
            app.FirstSummaryLabel.Interpreter = 'html';
            app.FirstSummaryLabel.Text = {'xxx'; 'xxx'};
            app.SecondSummaryLabel = uilabel(app.Panel_3);
            app.SecondSummaryLabel.Interpreter = 'html';
            app.SecondSummaryLabel.Text = {'xxx'; 'xxx'};
            % Show the figure after all components are created
            app.UIFigure.Visible = 'on';
        end
    end

    % App creation and deletion
    methods (Access = public)

        % Construct app
        function app = ContentScreen

            runningApp = getRunningApp(app);

            % Check for running singleton app
            if isempty(runningApp)

                % Create UIFigure and components
                createComponents(app)

                % Register the app with App Designer
                registerApp(app, app.UIFigure)

                % Execute the startup function
                runStartupFcn(app, @startupFcn)
            else

                % Focus the running singleton app
                figure(runningApp.UIFigure)

                app = runningApp;
            end

            if nargout == 0
                clear app
            end
        end

        % Code that executes before app deletion
        function delete(app)

            % Delete UIFigure when app is deleted
            delete(app.UIFigure)
        end
    end
end