classdef WelcomeScreen < matlab.apps.AppBase

    % Properties that correspond to app components
    properties (Access = public)
        UIFigure                      matlab.ui.Figure
        Panel                         matlab.ui.container.Panel
        InfoPanel                     matlab.ui.container.Panel
        Label                         matlab.ui.control.Label
        GeographicalAreaButtonGroup   matlab.ui.container.ButtonGroup
        SubregionextractionButton     matlab.ui.control.RadioButton
        WholeavailableregionButton    matlab.ui.control.RadioButton
        DownloadProcessandViewDataButton  matlab.ui.control.Button
        ModelButtonGroup              matlab.ui.container.ButtonGroup
        ECEarth3CCEuropeButton        matlab.ui.control.RadioButton
        HadGEM3GC31MMUKButton         matlab.ui.control.RadioButton
        NorESM2MMNorwayButton         matlab.ui.control.RadioButton
        MPIESM12LRGermanyButton       matlab.ui.control.RadioButton
        AWICM11MRGermanyButton        matlab.ui.control.RadioButton
        ECEarth3VegLREuropeButton     matlab.ui.control.RadioButton
        HadGEM3GC31LLUKButton         matlab.ui.control.RadioButton
        NorESM2LMNorwayButton         matlab.ui.control.RadioButton
        CMCCCM2SR5ItalyButton         matlab.ui.control.RadioButton
        CMCCESM2ItalyButton           matlab.ui.control.RadioButton
        IPSLCM5A2INCAFranceButton     matlab.ui.control.RadioButton
        MPIESM12HAMSwitzerlandButton  matlab.ui.control.RadioButton
        CNRMCM61HRFranceButton        matlab.ui.control.RadioButton
        ECEarth3EuropeButton          matlab.ui.control.RadioButton
        ECEarth3VegEuropeButton       matlab.ui.control.RadioButton
        ECEarth3AerChemEuropeButton   matlab.ui.control.RadioButton
        CNRMESM21FranceButton         matlab.ui.control.RadioButton
        CNRMCM61FranceButton          matlab.ui.control.RadioButton
        IPSLCM6ALRFranceButton        matlab.ui.control.RadioButton
        VariableButtonGroup           matlab.ui.container.ButtonGroup
        SnowdepthButton               matlab.ui.control.RadioButton
        SnowfallfluxButton            matlab.ui.control.RadioButton
        SurfacesnowamountButton       matlab.ui.control.RadioButton
        Panel_3                       matlab.ui.container.Panel
        Image2                        matlab.ui.control.Image
        Panel_2                       matlab.ui.container.Panel
        Label0                        matlab.ui.control.Label
        YearPanel                     matlab.ui.container.Panel
        StartingYearLabel             matlab.ui.control.Label
        FinalYearLabel                matlab.ui.control.Label
        FinalYearSpinner              matlab.ui.control.Spinner
        StartingYearSpinner           matlab.ui.control.Spinner
        ExperimentButtonGroup         matlab.ui.container.ButtonGroup
        SSP585Button                  matlab.ui.control.RadioButton
        SSP460Button                  matlab.ui.control.RadioButton
        SSP534OSButton                matlab.ui.control.RadioButton
        SSP126Button                  matlab.ui.control.RadioButton
        SSP119Button                  matlab.ui.control.RadioButton
        SSP434Button                  matlab.ui.control.RadioButton
        SSP245Button                  matlab.ui.control.RadioButton
        SSP370Button                  matlab.ui.control.RadioButton
    end

    
    properties (Access = private)
        pathToMLAPP = fileparts(mfilename('fullpath'));
    end
    

    % Callbacks that handle component events
    methods (Access = private)

        % Code that executes after component creation
        function startupFcn(app)
            % Posizino la finestra correttamente al centro dello schermo in
            % maniera indipendente dalla risoluzione di quest'ultimo
            % Ottengo la larghezza e l'altezza dello schermo
            screenSize = get(groot, 'ScreenSize');
            screenWidth = screenSize(3);
            screenHeight = screenSize(4);
        
            % Ottengo la larghezza e l'altezza della finestra dell'app
            appWidth = app.UIFigure.Position(3);
            appHeight = app.UIFigure.Position(4);
        
            % Calcolo le coordinate x e y per posizionare la finestra al centro
            x = (screenWidth - appWidth) / 2;
            y = (screenHeight - appHeight) / 2;
        
            % Imposto la posizione della finestra
            app.UIFigure.Position(1) = x;
            app.UIFigure.Position(2) = y;
            
            app.UIFigure.Name = 'CMIP6 Climate Projections Visualizer';
            borderColor = [0.64, 0.08, 0.18];
            app.Panel_2.BorderColor = borderColor;
            app.Panel_3.BorderColor = borderColor;
            app.ExperimentButtonGroup.BorderColor = borderColor;
            app.YearPanel.BorderColor = borderColor;
            app.YearPanel.BorderColor = borderColor;
            app.VariableButtonGroup.BorderColor = borderColor;
            app.ModelButtonGroup.BorderColor = borderColor;
            app.GeographicalAreaButtonGroup.BorderColor = borderColor;
            app.InfoPanel.BorderColor = borderColor;

            assignin('base', 'whichExperiment', 'SSP1-1.9');
            assignin('base', 'whichStartingYear', 2015);
            assignin('base', 'whichFinalYear', 2024);
            assignin('base', 'whichVariable', 'Snow depth');
            assignin('base', 'whichModel', 'EC-Earth3-AerChem (Europe)');
        end

        % Selection changed function: ExperimentButtonGroup
        function ExperimentButtonGroupSelectionChanged(app, event)
            selectedButton = app.ExperimentButtonGroup.SelectedObject;
            selectedExperiment = selectedButton.Text;
            assignin('base', 'whichExperiment', selectedExperiment);
            if (strcmp(selectedExperiment,'SSP5-3.4OS'))
                app.StartingYearSpinner.Limits = [2040 2100];
            else
                app.StartingYearSpinner.Limits = [2015 2100];
            end
        end

        % Selection changed function: VariableButtonGroup
        function VariableButtonGroupSelectionChanged(app, event)
            selectedButton = app.VariableButtonGroup.SelectedObject;
            selectedVariable = selectedButton.Text;
            assignin('base', 'whichVariable', selectedVariable);
        end

        % Selection changed function: ModelButtonGroup
        function ModelButtonGroupSelectionChanged(app, event)
            selectedButton = app.ModelButtonGroup.SelectedObject;
            selectedModel = selectedButton.Text;
            assignin('base', 'whichModel', selectedModel);
        end

        % Value changed function: StartingYearSpinner
        function StartingYearSpinnerValueChanged(app, event)
            value = app.StartingYearSpinner.Value;
            assignin('base', 'whichStartingYear', value);
        end

        % Value changed function: FinalYearSpinner
        function FinalYearSpinnerValueChanged(app, event)
            value = app.FinalYearSpinner.Value;
            assignin('base', 'whichFinalYear', value);
        end

        % Button pushed function: DownloadProcessandViewDataButton
        function DownloadProcessandViewDataButtonPushed(app, event)
            path0 = fullfile(app.pathToMLAPP, '.');

            % Chiudo lo schermo di benvenuto
            delete(app);

            % Aggiungo il path degli schermi al search path di Matlab
            % N.B.: La posizione non è casuale, l'addpath viene fatto qui
            %       perchè nel momento in cui l'app viene distrutta il 
            %       search path viene resettato da Matlab.
            %       Quindi creo il path degli schermi quando ancora l'app 
            %   	esiste, in modo che esista app.pathToMLAPP, e lo 
            %       aggiungo quando l'app viene distrutta.
            %       Questo mi permette di chiamare ContentScreen senza
            %       incorrere in errori.
            addpath(genpath(path0));

            % Apro lo schermo dei contenuti
            ContentScreen
        end
    end

    % Component initialization
    methods (Access = private)

        % Create UIFigure and components
        function createComponents(app)
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            % Get the file path for locating images
            pathToMLAPP = fileparts(mfilename('fullpath'));
            app.UIFigure = uifigure('Visible', 'off');
            app.UIFigure.Name = 'MATLAB App';
            app.Panel = uipanel(app.UIFigure);
            app.ExperimentButtonGroup = uibuttongroup(app.Panel);
            app.ExperimentButtonGroup.SelectionChangedFcn = createCallbackFcn(app, @ExperimentButtonGroupSelectionChanged, true);
            app.ExperimentButtonGroup.Title = 'Experiment';
            app.SSP370Button = uiradiobutton(app.ExperimentButtonGroup);
            app.SSP370Button.Text = 'SSP3-7.0';
            app.SSP245Button = uiradiobutton(app.ExperimentButtonGroup);
            app.SSP245Button.Text = 'SSP2-4.5';
            app.SSP434Button = uiradiobutton(app.ExperimentButtonGroup);
            app.SSP434Button.Text = 'SSP4-3.4';
            app.SSP119Button = uiradiobutton(app.ExperimentButtonGroup);
            app.SSP119Button.Text = 'SSP1-1.9';
            app.SSP119Button.Value = true;
            app.SSP126Button = uiradiobutton(app.ExperimentButtonGroup);
            app.SSP126Button.Text = 'SSP1-2.6';
            app.SSP534OSButton = uiradiobutton(app.ExperimentButtonGroup);
            app.SSP534OSButton.Text = 'SSP5-3.4OS';
            app.SSP460Button = uiradiobutton(app.ExperimentButtonGroup);
            app.SSP460Button.Text = 'SSP4-6.0';
            app.SSP585Button = uiradiobutton(app.ExperimentButtonGroup);
            app.SSP585Button.Text = 'SSP5-8.5';
            app.YearPanel = uipanel(app.Panel);
            app.YearPanel.Title = 'Year';
            app.StartingYearSpinner = uispinner(app.YearPanel);
            app.StartingYearSpinner.Limits = [2015 2100];
            app.StartingYearSpinner.ValueDisplayFormat = '%.0f';
            app.StartingYearSpinner.ValueChangedFcn = createCallbackFcn(app, @StartingYearSpinnerValueChanged, true);
            app.StartingYearSpinner.Value = 2015;
            app.FinalYearSpinner = uispinner(app.YearPanel);
            app.FinalYearSpinner.Limits = [2016 2100];
            app.FinalYearSpinner.ValueDisplayFormat = '%.0f';
            app.FinalYearSpinner.ValueChangedFcn = createCallbackFcn(app, @FinalYearSpinnerValueChanged, true);
            app.FinalYearSpinner.Value = 2024;
            app.FinalYearLabel = uilabel(app.YearPanel);
            app.FinalYearLabel.Text = 'Final Year';
            app.StartingYearLabel = uilabel(app.YearPanel);
            app.StartingYearLabel.Text = 'Starting Year';
            app.Panel_2 = uipanel(app.Panel);
            app.Label0 = uilabel(app.Panel_2);
            app.Label0.Text = 'CMIP6 Climate Projections Visualizer';
            app.Panel_3 = uipanel(app.Panel);
            app.Image2 = uiimage(app.Panel_3);
            app.Image2.ImageSource = fullfile(pathToMLAPP, 'res', 'top_banner_3.png');
            app.VariableButtonGroup = uibuttongroup(app.Panel);
            app.VariableButtonGroup.SelectionChangedFcn = createCallbackFcn(app, @VariableButtonGroupSelectionChanged, true);
            app.VariableButtonGroup.Title = 'Variable';
            app.SurfacesnowamountButton = uiradiobutton(app.VariableButtonGroup);
            app.SurfacesnowamountButton.Text = 'Surface snow amount';
            app.SnowfallfluxButton = uiradiobutton(app.VariableButtonGroup);
            app.SnowfallfluxButton.Text = 'Snowfall flux';
            app.SnowdepthButton = uiradiobutton(app.VariableButtonGroup);
            app.SnowdepthButton.Text = 'Snow depth';
            app.SnowdepthButton.Value = true;
            app.ModelButtonGroup = uibuttongroup(app.Panel);
            app.ModelButtonGroup.SelectionChangedFcn = createCallbackFcn(app, @ModelButtonGroupSelectionChanged, true);
            app.ModelButtonGroup.Title = 'Model';
            app.IPSLCM6ALRFranceButton = uiradiobutton(app.ModelButtonGroup);
            app.IPSLCM6ALRFranceButton.Text = 'IPSL-CM6A-LR (France)';
            app.CNRMCM61FranceButton = uiradiobutton(app.ModelButtonGroup);
            app.CNRMCM61FranceButton.Text = 'CNRM-CM6-1 (France)';
            app.CNRMESM21FranceButton = uiradiobutton(app.ModelButtonGroup);
            app.CNRMESM21FranceButton.Text = 'CNRM-ESM2-1 (France)';
            app.ECEarth3AerChemEuropeButton = uiradiobutton(app.ModelButtonGroup);
            app.ECEarth3AerChemEuropeButton.Text = 'EC-Earth3-AerChem (Europe)';
            app.ECEarth3AerChemEuropeButton.Value = true;
            app.ECEarth3VegEuropeButton = uiradiobutton(app.ModelButtonGroup);
            app.ECEarth3VegEuropeButton.Text = 'EC-Earth3-Veg (Europe)';
            app.ECEarth3EuropeButton = uiradiobutton(app.ModelButtonGroup);
            app.ECEarth3EuropeButton.Text = 'EC-Earth3 (Europe)';
            app.CNRMCM61HRFranceButton = uiradiobutton(app.ModelButtonGroup);
            app.CNRMCM61HRFranceButton.Text = 'CNRM-CM6-1-HR (France)';
            app.MPIESM12HAMSwitzerlandButton = uiradiobutton(app.ModelButtonGroup);
            app.MPIESM12HAMSwitzerlandButton.Text = 'MPI-ESM-1-2-HAM (Switzerland)';
            app.IPSLCM5A2INCAFranceButton = uiradiobutton(app.ModelButtonGroup);
            app.IPSLCM5A2INCAFranceButton.Text = 'IPSL-CM5A2-INCA (France)';
            app.CMCCESM2ItalyButton = uiradiobutton(app.ModelButtonGroup);
            app.CMCCESM2ItalyButton.Text = 'CMCC-ESM2 (Italy)';
            app.CMCCCM2SR5ItalyButton = uiradiobutton(app.ModelButtonGroup);
            app.CMCCCM2SR5ItalyButton.Text = 'CMCC-CM2-SR5 (Italy)';
            app.NorESM2LMNorwayButton = uiradiobutton(app.ModelButtonGroup);
            app.NorESM2LMNorwayButton.Text = 'NorESM2-LM (Norway)';
            app.HadGEM3GC31LLUKButton = uiradiobutton(app.ModelButtonGroup);
            app.HadGEM3GC31LLUKButton.Text = 'HadGEM3-GC31-LL (UK)';
            app.ECEarth3VegLREuropeButton = uiradiobutton(app.ModelButtonGroup);
            app.ECEarth3VegLREuropeButton.Text = 'EC-Earth3-Veg-LR (Europe)';
            app.AWICM11MRGermanyButton = uiradiobutton(app.ModelButtonGroup);
            app.AWICM11MRGermanyButton.Text = 'AWI-CM-1-1-MR (Germany)';
            app.MPIESM12LRGermanyButton = uiradiobutton(app.ModelButtonGroup);
            app.MPIESM12LRGermanyButton.Text = 'MPI-ESM1-2-LR (Germany)';
            app.NorESM2MMNorwayButton = uiradiobutton(app.ModelButtonGroup);
            app.NorESM2MMNorwayButton.Text = 'NorESM2-MM (Norway)';
            app.HadGEM3GC31MMUKButton = uiradiobutton(app.ModelButtonGroup);
            app.HadGEM3GC31MMUKButton.Text = 'HadGEM3-GC31-MM (UK)';
            app.ECEarth3CCEuropeButton = uiradiobutton(app.ModelButtonGroup);
            app.ECEarth3CCEuropeButton.Text = 'EC-Earth3-CC (Europe)';
            app.DownloadProcessandViewDataButton = uibutton(app.Panel, 'push');
            app.DownloadProcessandViewDataButton.ButtonPushedFcn = createCallbackFcn(app, @DownloadProcessandViewDataButtonPushed, true);
            app.DownloadProcessandViewDataButton.Icon = fullfile(pathToMLAPP, 'res', 'diagram_icon.png');
            app.DownloadProcessandViewDataButton.Text = 'Download, Process and View Data';
            app.GeographicalAreaButtonGroup = uibuttongroup(app.Panel);
            app.GeographicalAreaButtonGroup.Title = 'Geographical Area';
            app.WholeavailableregionButton = uiradiobutton(app.GeographicalAreaButtonGroup);
            app.WholeavailableregionButton.Enable = 'off';
            app.WholeavailableregionButton.Text = 'Whole available region';
            app.SubregionextractionButton = uiradiobutton(app.GeographicalAreaButtonGroup);
            app.SubregionextractionButton.Enable = 'off';
            app.SubregionextractionButton.Text = 'Sub-region extraction';
            app.SubregionextractionButton.Value = true;
            app.InfoPanel = uipanel(app.Panel);
            app.InfoPanel.Title = 'Info';
            app.Label = uilabel(app.InfoPanel);
            app.Label.Text = {'Please note that the processing time will depend on the amount of data you want to process,'; 'and the speed of your internet connection!'};
            % Show the figure after all components are created
            app.UIFigure.Visible = 'on';
        end
    end

    % App creation and deletion
    methods (Access = public)

        % Construct app
        function app = WelcomeScreen

            runningApp = getRunningApp(app);

            % Check for running singleton app
            if isempty(runningApp)

                % Create UIFigure and components
                createComponents(app)

                % Register the app with App Designer
                registerApp(app, app.UIFigure)

                % Execute the startup function
                runStartupFcn(app, @startupFcn)
            else

                % Focus the running singleton app
                figure(runningApp.UIFigure)

                app = runningApp;
            end

            if nargout == 0
                clear app
            end
        end

        % Code that executes before app deletion
        function delete(app)

            % Delete UIFigure when app is deleted
            delete(app.UIFigure)
        end
    end
end